classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FossilUI             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        AboutMenu            matlab.ui.container.Menu
        OpenFileMenu         matlab.ui.container.Menu
        ProgramInfoMenu      matlab.ui.container.Menu
        EditMenu             matlab.ui.container.Menu
        LeftPanel            matlab.ui.container.Panel
        EditorPane           matlab.ui.control.TextArea
        RightPanel           matlab.ui.container.Panel
        TabGroup             matlab.ui.container.TabGroup
        OutputTab            matlab.ui.container.Tab
        OutputPane           matlab.ui.control.HTML
        RUNButton            matlab.ui.control.Button
        ProgramNameLabel     matlab.ui.control.Label
        ProgramName          matlab.ui.control.EditField
        ExtensionsDropDown   matlab.ui.control.DropDown
        SyntaxTab            matlab.ui.container.Tab
        SyntaxPane           matlab.ui.control.HTML
        CHECKSYNTAXButton    matlab.ui.control.Button
        CompileOptionsTab    matlab.ui.container.Tab
        ForceFFchckbx        matlab.ui.control.CheckBox
        UpdateOptionsButton  matlab.ui.control.Button
        FDECchckbx           matlab.ui.control.CheckBox
        FolderTreeTab        matlab.ui.container.Tab
        Tree                 matlab.ui.container.Tree
        Node                 matlab.ui.container.TreeNode
        Node2                matlab.ui.container.TreeNode
        Node3                matlab.ui.container.TreeNode
        Node4                matlab.ui.container.TreeNode
    end

    
    properties (Access = public)
        ver = '0.9.1'; % current version of the app
        release_date = 'June 22, 2020'; % release date of this version
        ss = get(groot, "ScreenSize"); % [x, y, width, height]
                
        % saves output history
        output_history = "";
        
        % compiling options related to the number of checkboxes
        compile_options = {'-ffree-form', '-fdec'};
        compile_option_names = {'ForceFFchckbx', 'FDECchckbx'};
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, infoapp)
            
            %%%%%% ----- for UI info, see docs/LAYOUT.md ----- %%%%%%
            cd ~/Documents/GitHub/Fossil
            
            
            %%%% --- INITIALIZE STARTUP POSITIONS --- %%%%
            % FOSSIL UI = full screen
            app.FossilUI.Position = app.ss; % = app.screen_size
            % set Fossil UI name to "Fossil v[version] - [/dir]"
            app.FossilUI.Name = ['Fossil v' app.ver ' - ' pwd];
            
            %% LEFT PANEL component positions %%
            % Editor pane on main stage, spare room above
            app.LeftPanel.Position = [1, 1, app.ss(3)/2, app.ss(4)-118];
            app.EditorPane.Position = [10, 10, ...
                app.ss(3)/2-20, app.ss(4)-170];
            
            %% RIGHT PANEL component positions %%
            % Tabs dimensions are same as panel minus a small amount to fit
            app.RightPanel.Position = [app.ss(3)/2, 1, ...
                app.ss(3)/2, app.ss(4)-118];
            app.TabGroup.Position = [10, 10, ...
                app.ss(3)/2-20, app.ss(4)-135];
            % ------ TAB 1 - OUTPUT ------ %
            app.OutputPane.Position = [20, 20, ...
                app.ss(3)/2-60, app.ss(4)-230];
            app.RUNButton.Position = [20, app.ss(4)-200, 80, 30];
            app.ProgramName.Position = [200, app.ss(4)-200, 120, 30];
            app.ProgramNameLabel.Position = [70, app.ss(4)-200, 120, 30];
            app.ExtensionsDropDown.Position = [340, app.ss(4)-200, ...
                60, 30];
            % ------ TAB 2 - SYNTAX ------ %
            app.SyntaxPane.Position = [20, 20, ...
                app.ss(3)/2-60, app.ss(4)-230];
            app.CHECKSYNTAXButton.Position = [20, app.ss(4)-200, 160, 30];
            
            % ------ TAB 3 - COMPILE OPTIONS ------ %
            app.UpdateOptionsButton.Position = [20, app.ss(4)-200, 160, 30];
            app.ForceFFchckbx.Position = [20 app.ss(4)-240 120 20];
            app.FDECchckbx.Position = [20 app.ss(4)-260 200 20];


            %%%% --- INITIALIZE OBJECT COLORS & FONTS --- %%%%
            % set Fossil UI name to "Fossil v[version] - [/dir]"
            app.ProgramNameLabel.FontSize = 12;
            app.EditorPane.FontSize = 13;
            app.EditorPane.FontName = 'Geneva';
            app.EditorPane.BackgroundColor = [0.94, 0.94, 0.94];
            app.ExtensionsDropDown.FontSize = 12;
            app.ExtensionsDropDown.FontName = 'Helvetica';
            
            
            %%%% --- INITIALIZE STRINGS --- %%%%
            app.ProgramNameLabel.Text = "Program Name:";
            app.ExtensionsDropDown.Value = '.f90';
            app.ExtensionsDropDown.Items = {'.f90', '.f95', '.f03', ...
                '.f08', '.f', '.for', '.ftn'};
            
            
            %%%% --- SET DEFAULT PROGRAM: Hello World! --- %%%%
            % Set default as a simple Hello World Program
            HelloWorldStr = sprintf('%s\n%s\n%s\n', ...
                "PROGRAM hello", ...
                "  PRINT *,""Hello World!""", ...
                "END PROGRAM hello");
            set(app.EditorPane, 'Value', HelloWorldStr);
            set(app.ProgramName, 'Value', "hello")
            
            
            %%%% --- INITIALIZE BASIC PANES IN TABS --- %%%%
            % Set default output pane to a set of instructions
            app.OutputPane.HTMLSource = fullfile(pwd, 'tools/output/commandline.html');
            
            % Set default syntax HTML obj to data-driven HTML file and
            %  initialize with the default program
            app.SyntaxPane.HTMLSource = fullfile(pwd,'tools/syntax/fortran.html');
            %code = get(app.EditorPane, 'Value');
            %app.SyntaxPane.Data = sprintf('%s<br>', code{:});
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            %%% --- Run compile command in terminal & show output --- %%%
            % Open a fortran file (type is based on extension drop-down 
            %  menu and write the code from the editor pane to it. The file
            %  name is stored in 'file_name' with file ID = file_ID.
            file_name = [get(app.ProgramName, 'Value') app.ExtensionsDropDown.Value];
            file_ID = fopen(['test/' file_name],'w');
            fprintf(file_ID,'%s\n', app.EditorPane.Value{:});
            fclose(file_ID); % close file
            
            % Run the terminal command, printing all good and bad outputs,
            %  flag is currently unused, build tab here?
            [~, output] = system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                '/usr/local/bin/gfortran -o f ' file_name ';', + ...
                ' ./f']);
            
            %https://gcc.gnu.org/onlinedocs/gcc-5.1.0/gfortran.pdf
            
            % save timestamp for console window
            timestamp = sprintf('%s%s%s', ...
                "<time>", ...
                [' //// ' file_name ' --- ' string(datetime('now'))], ...
                "</time>");
            
            % write the last output to history for console
            app.output_history = [app.output_history sprintf('\n%s%s%s', ...
                output), timestamp, "<br>"];
            
            
            %code = get(app.EditorPane, 'Value');
            app.OutputPane.Data = sprintf('%s', app.output_history);
                        
            % Remove 3 different output files:
            %  - tools/temp, which is the entire temp folder
            %  - test/[program name].[ext], with the current program name
            %  - test/f.unix, exe file from compiling f90
            delete('tools/temp/*')
            if isfile(['test/' file_name])
                system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                    'rm ' file_name]);
            end
            if isfile('test/f')
                system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                    'rm f']);
            end
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            
            %% --- ABOUT MENU creation/selection --- %%
            % open the about menu information: text, title, and image
            text = {'Programming IDE for antiquated languages,',...
                [' Current version = ' app.ver],...
                [' Released on ' app.release_date],...
                ' See the repository at https://github.com/nrwade0/Fossil'};
            title = "About Fossil IDE";
            [imgdata, imgcmap] = imread('res/car.jpg');
            
            % Change options to TeX interpreter, modal dialog box
            options.Interpreter = 'tex';
            options.WindowStyle = 'modal';
            
            % Create box and reposition in the middle
            m = msgbox(text, title, "custom", imgdata, imgcmap, options);
            m.Position = [(app.ss(3)-330)/2 (app.ss(4)-90)/2 330 90];
            
        end

        % Button pushed function: CHECKSYNTAXButton
        function CHECKSYNTAXButtonPushed(app, event)
            
            %% --- Output HTML Syntax in Fortran --- %%
            % Gets the code data in the editor pane and writes to the 
            %  connected syntax file 'fortran.html' <div=DataDisplay>. In 
            %  the future, change syntax coloring...
            code = get(app.EditorPane, 'Value');
            app.SyntaxPane.Data = sprintf('%s<br>', code{:});
            
        end

        % Button pushed function: UpdateOptionsButton
        function UpdateOptionsButtonPushed(app, event)
            
            %%% --- Determines the compile options selected --- %%%
            % We parse and examine the checkboxes by first isolating the
            %  UI objects with the phrase 'chckbox' in the name. Then we
            %  set a binary (0/1) columnar matrix (called 'selected') to
            %  store the Y/N of the checkboxes. The compile options are
            %  indexed using this matrix and the public property
            %  'app.compile_options'
            fieldNames = fieldnames(app);
            chckbxNames = fieldNames(contains(fieldNames,'chckbx'));
            
            % Binary array initialized then filled with 1's where boxes are
            %  checked.
            selected = zeros(1, length(chckbxNames));
            for i = 1:numel(chckbxNames)
                if(app.(chckbxNames{i}).Value == true)
                    selected(i) = 1;
                end
            end
            
            % Display selected options
            app.compile_options{selected == 1};
            
        end

        % Value changed function: EditorPane
        function EditorPaneValueChanged(app, event)
            
            %event.Value
            %event.PreviousValue
            RUNButtonPushed(app, event)
            %{
            code = get(app.EditorPane, 'Value');
            app.SyntaxPane.Data = sprintf('%s<br>', code{:});
            %}
            
        end

        % Key press function: FossilUI
        function KeyboardShortcutsUI(app, event)
            
            %%%%% ----- KEYBOARD SHORTCUTS ----- %%%%%
            % Executed within the frame of the UI
            %  i.e. cannot have on cursor in the editor pane...
            key = event.Key;
            if(key == "backquote")
                RUNButtonPushed(app, event)
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FossilUI = uifigure('Visible', 'off');
            app.FossilUI.Name = 'Fossil 0.9';
            app.FossilUI.KeyPressFcn = createCallbackFcn(app, @KeyboardShortcutsUI, true);
            app.FileMenu = uimenu(app.FossilUI);
            app.FileMenu.Text = 'File';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.OpenFileMenu = uimenu(app.FileMenu);
            app.OpenFileMenu.Text = 'Open File';
            app.ProgramInfoMenu = uimenu(app.FileMenu);
            app.ProgramInfoMenu.Text = 'Program Info';
            app.EditMenu = uimenu(app.FossilUI);
            app.EditMenu.Text = 'Edit';
            app.LeftPanel = uipanel(app.FossilUI);
            app.EditorPane = uitextarea(app.LeftPanel);
            app.EditorPane.ValueChangedFcn = createCallbackFcn(app, @EditorPaneValueChanged, true);
            app.RightPanel = uipanel(app.FossilUI);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.OutputPane = uihtml(app.OutputTab);
            app.RUNButton = uibutton(app.OutputTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ProgramNameLabel = uilabel(app.OutputTab);
            app.ProgramNameLabel.Text = 'Program Name';
            app.ProgramName = uieditfield(app.OutputTab, 'text');
            app.ExtensionsDropDown = uidropdown(app.OutputTab);
            app.ExtensionsDropDown.Items = {'.f90', '.f95', '.f03', '.f08', '.f', '.for', '.ftn'};
            app.ExtensionsDropDown.Value = '.f90';
            app.SyntaxTab = uitab(app.TabGroup);
            app.SyntaxTab.Title = 'Syntax';
            app.SyntaxPane = uihtml(app.SyntaxTab);
            app.CHECKSYNTAXButton = uibutton(app.SyntaxTab, 'push');
            app.CHECKSYNTAXButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKSYNTAXButtonPushed, true);
            app.CHECKSYNTAXButton.Text = 'CHECK SYNTAX';
            app.CompileOptionsTab = uitab(app.TabGroup);
            app.CompileOptionsTab.Title = 'Compile Options';
            app.ForceFFchckbx = uicheckbox(app.CompileOptionsTab);
            app.ForceFFchckbx.Text = 'Force free-form';
            app.UpdateOptionsButton = uibutton(app.CompileOptionsTab, 'push');
            app.UpdateOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateOptionsButtonPushed, true);
            app.UpdateOptionsButton.Text = 'Update Options';
            app.FDECchckbx = uicheckbox(app.CompileOptionsTab);
            app.FDECchckbx.Text = 'DEC compatibility mode';
            app.FolderTreeTab = uitab(app.TabGroup);
            app.FolderTreeTab.Title = 'Folder Tree';
            app.Tree = uitree(app.FolderTreeTab);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            % Show the figure after all components are created
            app.FossilUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FossilUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FossilUI)
        end
    end
end